classdef etchAsketchapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AlgorithmSelectionDropDown  matlab.ui.control.DropDown
        AlgorithmSelectionDropDownLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        TextLocationY_button        matlab.ui.control.EditField
        Run_button                  matlab.ui.control.Button
        EstimatedTime_text          matlab.ui.control.EditField
        AddTextToImageLabel_3       matlab.ui.control.Label
        Reset_button                matlab.ui.control.Button
        TextLocationX_button        matlab.ui.control.EditField
        AddTextToImageLabel_4       matlab.ui.control.Label
        Preview_button              matlab.ui.control.Button
        EnterText                   matlab.ui.control.EditField
        AddTextToImageLabel         matlab.ui.control.Label
        ResolutionSlider            matlab.ui.control.Slider
        BrightnessSlider            matlab.ui.control.Slider
        ContrastSlider              matlab.ui.control.Slider
        OpenImage_button            matlab.ui.control.Button
        FileName_text               matlab.ui.control.EditField
        AddTextToImageLabel_2       matlab.ui.control.Label
        ResolutionSliderLabel       matlab.ui.control.Label
        BrightnessSliderLabel       matlab.ui.control.Label
        ContrastSliderLabel         matlab.ui.control.Label
        ROBOTICETCHASKETCHLabel     matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        Knob_2                      matlab.ui.control.Knob
        Knob                        matlab.ui.control.Knob
        figure                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Current image
        inputText_position = [300, 300] % Description
        backupimg
        resolutionVal
        contrastVal
        brightnessVal
        algo = "Canny Edges"
    end
    
    methods (Access = private)
        %%% Our Functions %%%
        function updateImage(app)
            app.Image.ImageSource = cat(3, app.img, app.img, app.img);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImage_button
        function OpenImage_buttonButtonPushed(app, event)
            imgName = ' hey'; % uigetfile('*.jpg');
            % app.img = im2gray(imread(imgName));            
            app.img = im2gray(imread('basepic.jpg'));
            app.img = imresize(app.img, [500 800]);
            app.updateImage();
            app.FileName_text.Value = imgName;
            app.backupimg = app.img;

        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = app.ContrastSlider.Value;
            if value > 0
                low_out = interp1([-5, 5], [0, 1], value - 5);
                high_out = 1 - low_out;
                app.img = imadjust(app.img, [low_out high_out], []);

            elseif value < 0
                low_out = 0;
                high_out = interp1([-5, 5], [0, 1], value);
                % app.img = imadjust(app.img, [low_out high_out], []);
                app.img = imadjust(app.img, [0 1], [0 1]);

            end
            app.updateImage();
            app.contrastVal = value;
        end

        % Button pushed function: Preview_button
        function Preview_buttonButtonPushed(app, event)
            app.Image.Visible = false;
            app.figure.Parent = app.Panel;
            app.figure.Position = [40,20,525,320];
            l = animatedline('Parent', app.figure);   
           
            [numRows, numCols] = size(app.img);
            app.figure.XLim = [0 numRows];
            app.figure.YLim = [0 numCols];
            
            if app.algo == "Canny Edges"
                threshold = [.1 .6]; % [low, high]
                edges = imcomplement(edge(app.img, 'Canny', threshold));
                [row, col] = find(~edges);
            elseif app.algo == "Contours"
                [row, col] = find(app.img < 255);
            elseif app.algo == "Column Modulation"
                disp('Not done')
            else
                disp("Error in algorithm selection")
            end
            mat = [row col];
            
            pointsLeft = 2;
            curX = 0;
            curY = 0;
            while pointsLeft > 1
                numLeft = size(mat);
                pointsLeft = numLeft(1);
                disp(pointsLeft);

                est_timeLeft = pointsLeft * 0.1;                
                app.EstimatedTime_text.Value = string(est_timeLeft);
                
                % Find closest point to current location
                index = dsearchn(mat, [curX curY]);
                closest_col = mat(index, 1);
                closest_row = mat(index, 2);               
                
                % Clear index from matrix
                mat(index, :) = [];

                % Update current x & y positions
                curX = closest_col;
                curY = closest_row;
            
                % Update animated line figure
                addpoints(l, curY, numCols - curX)
                drawnow limitrate
            end
        end

        % Value changed function: EnterText
        function EnterTextValueChanged(app, event)
            inputText = app.EnterText.Value;
            disp(inputText)
            img_withText = insertText(app.img, app.inputText_position, inputText, FontSize = 64, TextColor='black', BoxOpacity = 0);
            app.Image.ImageSource = img_withText;
        end

        % Value changed function: TextLocationX_button
        function TextLocationX_buttonValueChanged(app, event)
            value = app.TextLocationX_button.Value;
            disp(value)
            disp(type(value))
       
            app.inputText_position(1) = str2num(value);
            app.EnterTextValueChanged();
        end

        % Value changed function: TextLocationY_button
        function TextLocationY_buttonValueChanged(app, event)
            app.inputText_position(2) = int(app.TextLocationY_button.Value);
            app.EnterTextValueChanged();            
        end

        % Value changed function: ResolutionSlider
        function ResolutionSliderValueChanged(app, event)
            value = app.ResolutionSlider.Value;
            scale = interp1([-5, 5], [0.25, 1], value);
            app.img = imresize(app.img, scale);
            app.updateImage();
            app.resolutionVal = value;
        end

        % Button pushed function: Reset_button
        function Reset_buttonButtonPushed(app, event)
            app.img = app.backupimg;
            app.contrastVal = 0;
            app.ContrastSlider.Value = 0;
            app.brightnessVal = 0;
            app.BrightnessSlider.Value = 0;
            app.resolutionVal = 0;
            app.ResolutionSlider.Value = 0;
            app.updateImage();
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            app.img = app.img + (value * 10);
            app.updateImage();
            app.brightnessVal = value;
        end

        % Value changed function: AlgorithmSelectionDropDown
        function AlgorithmSelectionDropDownValueChanged(app, event)
            app.algo = app.AlgorithmSelectionDropDown.Value;
            disp(app.algo)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.figure = uiaxes(app.UIFigure);
            app.figure.XTick = [];
            app.figure.YTick = [];
            app.Panel = uipanel(app.UIFigure);
            app.Knob = uiknob(app.Panel, 'continuous');
            app.Knob.MajorTicks = [];
            app.Knob.MinorTicks = [];
            app.Knob_2 = uiknob(app.Panel, 'continuous');
            app.Knob_2.MajorTicks = [];
            app.Knob_2.MinorTicks = [];
            app.ROBOTICETCHASKETCHLabel = uilabel(app.UIFigure);
            app.ROBOTICETCHASKETCHLabel.Text = 'ROBOTIC ETCH-A-SKETCH';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.ResolutionSliderLabel = uilabel(app.UIFigure);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.AddTextToImageLabel_2 = uilabel(app.UIFigure);
            app.AddTextToImageLabel_2.Text = 'File Name:';
            app.FileName_text = uieditfield(app.UIFigure, 'text');
            app.FileName_text.Editable = 'off';
            app.FileName_text.Value = 'image.jpg';
            app.OpenImage_button = uibutton(app.UIFigure, 'push');
            app.OpenImage_button.ButtonPushedFcn = createCallbackFcn(app, @OpenImage_buttonButtonPushed, true);
            app.OpenImage_button.Text = 'Open Image';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [-5 5];
            app.ContrastSlider.MajorTicks = [-5 -3 -1 1 3 5];
            app.ContrastSlider.MajorTickLabels = {'- 5', '- 3', '- 1', '+ 1', '+ 3', '+ 5'};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [-4 -2 -1 0 1 2 4];
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [-5 5];
            app.BrightnessSlider.MajorTicks = [-5 -3 -1 1 3 5];
            app.BrightnessSlider.MajorTickLabels = {'- 5', '- 3', '- 1', '+ 1', '+ 3', '+ 5'};
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [-4 -2 -1 0 1 2 4];
            app.ResolutionSlider = uislider(app.UIFigure);
            app.ResolutionSlider.Limits = [-5 5];
            app.ResolutionSlider.MajorTicks = [-5 -3 -1 1 3 5];
            app.ResolutionSlider.MajorTickLabels = {'- 5', '- 3', '- 1', '+ 1', '+ 3', '+ 5'};
            app.ResolutionSlider.ValueChangedFcn = createCallbackFcn(app, @ResolutionSliderValueChanged, true);
            app.ResolutionSlider.MinorTicks = [-4 -2 -1 0 1 2 4];
            app.AddTextToImageLabel = uilabel(app.UIFigure);
            app.AddTextToImageLabel.Text = 'Add Text To Image: ';
            app.EnterText = uieditfield(app.UIFigure, 'text');
            app.EnterText.ValueChangedFcn = createCallbackFcn(app, @EnterTextValueChanged, true);
            app.EnterText.Value = 'Sample Text';
            app.Preview_button = uibutton(app.UIFigure, 'push');
            app.Preview_button.ButtonPushedFcn = createCallbackFcn(app, @Preview_buttonButtonPushed, true);
            app.Preview_button.Text = 'Preview Path';
            app.AddTextToImageLabel_4 = uilabel(app.UIFigure);
            app.AddTextToImageLabel_4.Text = 'Text Location (x, y):';
            app.TextLocationX_button = uieditfield(app.UIFigure, 'text');
            app.TextLocationX_button.ValueChangedFcn = createCallbackFcn(app, @TextLocationX_buttonValueChanged, true);
            app.TextLocationX_button.Value = 'x';
            app.Reset_button = uibutton(app.UIFigure, 'push');
            app.Reset_button.ButtonPushedFcn = createCallbackFcn(app, @Reset_buttonButtonPushed, true);
            app.Reset_button.Text = 'Reset';
            app.AddTextToImageLabel_3 = uilabel(app.UIFigure);
            app.AddTextToImageLabel_3.Text = 'Estimated Time To Print: ';
            app.EstimatedTime_text = uieditfield(app.UIFigure, 'text');
            app.EstimatedTime_text.Editable = 'off';
            app.EstimatedTime_text.Value = 'XX:XX';
            app.Run_button = uibutton(app.UIFigure, 'push');
            app.Run_button.Text = 'Run!';
            app.TextLocationY_button = uieditfield(app.UIFigure, 'text');
            app.TextLocationY_button.ValueChangedFcn = createCallbackFcn(app, @TextLocationY_buttonValueChanged, true);
            app.TextLocationY_button.Value = 'y';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'EaS_img.jpg');
            app.AlgorithmSelectionDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmSelectionDropDownLabel.Text = 'Algorithm Selection';
            app.AlgorithmSelectionDropDown = uidropdown(app.UIFigure);
            app.AlgorithmSelectionDropDown.Items = {'Canny Edges', 'Contours', 'Column Modulation'};
            app.AlgorithmSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmSelectionDropDownValueChanged, true);
            app.AlgorithmSelectionDropDown.Value = 'Canny Edges';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = etchAsketchapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end